classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureLoggerUIFigure           matlab.ui.Figure
        RefreshMenu                     matlab.ui.container.Menu
        SyncMenu                        matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportSHREClikeMenu             matlab.ui.container.Menu
        AdminMenu                       matlab.ui.container.Menu
        FrontendURLMenu                 matlab.ui.container.Menu
        FrontendURLInfo                 matlab.ui.container.Menu
        BackendURLMenu                  matlab.ui.container.Menu
        BackendURLInfo                  matlab.ui.container.Menu
        GestureDataFolderMenu           matlab.ui.container.Menu
        GestureDataFolderInfo           matlab.ui.container.Menu
        AnnotationFolderMenu            matlab.ui.container.Menu
        AnnotationFolderInfo            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        AllPanel                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        GesturesTab                     matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GesturesTabGroup                matlab.ui.container.TabGroup
        EditGestureTab                  matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GestureNameEditField            matlab.ui.control.EditField
        GestureNameEditFieldLabel       matlab.ui.control.Label
        StreamGestureCheckBox           matlab.ui.control.CheckBox
        EditGesturePanel                matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        EditGestureTree                 matlab.ui.container.Tree
        DeleteSubAnnotationButton       matlab.ui.control.Button
        AddSubAnnotationButton          matlab.ui.control.Button
        AnnotationPropertiesPanel       matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        AnnotationNameEditField         matlab.ui.control.EditField
        AnnotationNameEditFieldLabel    matlab.ui.control.Label
        PredictedPropertiesOptionalPanel  matlab.ui.container.Panel
        PredictedPropertiesGridLayout   matlab.ui.container.GridLayout
        EndFrameEditField               matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel          matlab.ui.control.Label
        StartFrameEditField             matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        AnnotationTypeButtonGroup       matlab.ui.container.ButtonGroup
        ContinuousButton                matlab.ui.control.RadioButton
        DiscreteButton                  matlab.ui.control.RadioButton
        InstancesTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GestureInstanceTable            matlab.ui.control.Table
        OpenGestureInstanceButton       matlab.ui.control.Button
        GestureInstanceLabel            matlab.ui.control.Label
        DemonstrationTab                matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        ViewDemonstrationButton         matlab.ui.control.Button
        DemonstrationStatusLabel        matlab.ui.control.Label
        CopyDemonstrationURLToClipboard  matlab.ui.control.Button
        DemonstrationURL                matlab.ui.control.Hyperlink
        DemonstrationDurationEditField  matlab.ui.control.NumericEditField
        DurationsecondsLabel            matlab.ui.control.Label
        GetDemonstrationUrlButton       matlab.ui.control.Button
        GridLayout4_2                   matlab.ui.container.GridLayout
        AddGestureButton                matlab.ui.control.Button
        GestureList                     matlab.ui.control.ListBox
        GesturesLabel                   matlab.ui.control.Label
        TrialTemplateTab                matlab.ui.container.Tab
        GridLayout3_3                   matlab.ui.container.GridLayout
        TrialDataPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        TrialPropertiesPanel            matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        TrialInstructionsTextArea       matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        TrialIDEditField                matlab.ui.control.EditField
        TrialIDEditFieldLabel           matlab.ui.control.Label
        TrialNameEditField              matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        TrialGesturesPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        TrialGesturesTable              matlab.ui.control.Table
        AddGestureToTrialButton         matlab.ui.control.Button
        GridLayout4_3                   matlab.ui.container.GridLayout
        AddTrialButton                  matlab.ui.control.Button
        TrialTemplateList               matlab.ui.control.ListBox
        TrialTemplatesLabel             matlab.ui.control.Label
        ParticipantsTab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        TrialsTab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ParticipantTrialsTable          matlab.ui.control.Table
        AddTrialToParticipantButton     matlab.ui.control.Button
        PerformedGesturesTab            matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        ParticipantGesturesTable        matlab.ui.control.Table
        OpenParticipantGestureAnnot     matlab.ui.control.Button
        ParticipantDataTab              matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        ParticipantInfoTextArea         matlab.ui.control.TextArea
        AdditionalParticipantInformationroughnotespaceLabel  matlab.ui.control.Label
        CopytoClipboardButton           matlab.ui.control.Button
        ParticipantURL                  matlab.ui.control.Hyperlink
        ParticipantURLButton            matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        AddParticipantButton            matlab.ui.control.Button
        ParticipantList                 matlab.ui.control.ListBox
        ParticipantsLabel               matlab.ui.control.Label
        CapturesTab                     matlab.ui.container.Tab
        GridLayout3_4                   matlab.ui.container.GridLayout
        GridLayout4_4                   matlab.ui.container.GridLayout
        CaptureStatusDropDown           matlab.ui.control.DropDown
        CaptureStatusDropDownLabel      matlab.ui.control.Label
        CountEditField                  matlab.ui.control.NumericEditField
        CountEditFieldLabel             matlab.ui.control.Label
        OpenUnannotatedGestureButton    matlab.ui.control.Button
        CapturesTable                   matlab.ui.control.Table
        CapturesLabel                   matlab.ui.control.Label
    end

    properties (Access = public)
         db % Connected database
    end

    properties (Access = private)
        requestManager % Holds the object which communicates with the server
        gestureDialog % Holds the gesture dialog if opened
        dialogOpen = false;

        project
        annotFolder
        dataFolder
        currentGiid
    end
    
    methods (Static)
        function [giid, success] = getGiid(table)
            index = table.Selection;
            
            if isempty(index)
                giid = [];
                success = false;
            else
                giid = table.Data{index, 'giid'};
                success = true;
            end
        end
    end

    methods (Access = private)

        function success = removeTrial(app, pid, tiid)
            success = app.requestManager.removeTrial(pid, tiid);
        end

        function success = cancelTrial(app, pid, tiid)
            app.db.updateTrialInstanceStatus(tiid, "failed")
            success = app.requestManager.removeTrial(pid, tiid);
        end

        function success = sendTrial(app, tiid)
            trialInstance = app.db.getTrialInstanceFromId(tiid);
            trialStruct = app.db.getTrialStruct(string(tiid));
            
            success = app.requestManager.pushTrial(trialInstance.pid, trialStruct);

            if success
                app.db.updateTrialInstanceStatus(tiid, "pushed");
            end
        end

        function [tid, success] = trialSelector(app)
            [tids, names] = app.db.getTemplateIdsAndNames();
            
            % set defaults
            tid = []; 
            success = false;

            if isempty(tids)
                app.warn("No trial templates to choose from")
                return;
            end

            [index, tf] = listdlg( ...
                'ListString', names, ...
                'PromptString', "Choose a trial", ...
                'SelectionMode', 'single');

            if ~tf
                return
            end

            success = true;
            tid = tids(index);
        end

        function inform(app, message)
            uialert( ...
                app.GestureLoggerUIFigure, ...
                message, ...
                'Information', ...
                'Icon', 'info');
        end

        function warnWithCallback(app, message, callback)
            uialert( ...
                app.GestureLoggerUIFigure, ...
                message, ...
                'Warning', ...
                'Icon', 'warning', ...
                'CloseFcn', @(~, ~) callback());
        end

        function warn(app, message)
            uialert( ...
                app.GestureLoggerUIFigure, ...
                message, ...
                'Warning', ...
                'Icon', 'warning');
        end

        function success = confirm(app, message)
            selection = uiconfirm( ...
                app.GestureLoggerUIFigure, ...
                message, ...
                'Confirm', ...
                'Icon', 'question');

            success = selection == "OK";
        end

        function urlParam = getFrontendUrlQueryParam(app)
            urlParam = strcat("url=",urlencode(app.requestManager.baseUrl));
        end

        function [pos, success] = getPosOfGestureInTrial(app)
            rowIndex = app.TrialGesturesTable.Selection;
            pos = [];  % set to default if not selected
            success = ~isempty(rowIndex);

            if success
                pos = app.TrialGesturesTable.Data.Properties.RowNames(rowIndex);
            end
        end

        function [tid, success] = getPid(app)
            tid = app.ParticipantList.Value;
            success = ~isempty(tid);
        end

        function [gid, success] = getGid(app)
            gid = app.GestureList.Value;
            success = ~isempty(gid);
        end

        function [tid, success] = getTid(app)
            tid = app.TrialTemplateList.Value;
            success = ~isempty(tid);
        end

        function updateEditGestureTab(app)
            [gid, success] = app.getGid();

            if success
                app.updateGestureLevelProperties();
                
                if app.db.getIsStreamOfGesture(gid)
                    app.GestureNameEditField.Enable = "on";
                    app.EditGesturePanel.Visible = "off";
                else
                    app.GestureNameEditField.Enable = "off";
                    app.EditGesturePanel.Visible = "on";
                    app.updateEditGestureTree();
                    app.updateAnnotationPropertiesPanel();
                end
            end
        end

        function updateGestureLevelProperties(app)
            [gid, success] = app.getGid();

            if success
                name = app.db.getNameOfGesture(gid);
                isStream = app.db.getIsStreamOfGesture(gid);

                app.GestureNameEditField.Value = name;
                app.StreamGestureCheckBox.Value = isStream;
            end            
        end

        function updateEditGestureTree(app)
            [gid, success] = app.getGid();

            app.EditGestureTree.Children.delete();

            if success
                json = app.db.getGestureJson(gid);
                annot = basicReplayGrid.annotFromJson(json);
                populateTree(annot);
                app.updateAnnotationPropertiesPanel();
            end

            function populateTree(annot)
                % populate new tree
                populateNode(app.EditGestureTree, annot);

                function node = populateNode(parent, annot)
                    node = uitreenode(parent, "Text", annot.name, "NodeData", annot);
                    cellfun(@(child) populateNode(node, child), annot.children);
                    expand(node);
                end
            end
        end

        function saveCurrentAnnotation(app)
            [gid, success] = app.getGid();

            if success
                children = app.EditGestureTree.Children;

                if isempty(children)
                    return
                end

                rootAnnot = children(1).NodeData;
                json = rootAnnot.getJson;

                app.db.updateGestureJson(gid, json);
                app.db.updateGestureName(gid, rootAnnot.name);
            else
                app.warn("Must select a gesture before attempting to save")
            end
        end

        function updateAnnotationPropertiesPanel(app)
            selectedNodes = app.EditGestureTree.SelectedNodes;
            
            if isempty(selectedNodes) 
                app.AnnotationPropertiesPanel.Visible = "off";
                return;
            end

            app.AnnotationPropertiesPanel.Visible = "on";
            app.AnnotationNameEditField.Value = selectedNodes.NodeData.name;

            switch selectedNodes.NodeData.annotType
                case "discrete"
                    setPropertiesToDiscrete(selectedNodes);
                case  "continuous"
                    setPropertiesToContinuous(selectedNodes);
            end

            function setPropertiesToContinuous(node)
                app.AnnotationTypeButtonGroup.SelectedObject = app.ContinuousButton;
                
                fieldStruct.StartFrame = node.NodeData.startFrame;
                fieldStruct.EndFrame = node.NodeData.endFrame;

                components = generateFields( ...
                    app.PredictedPropertiesGridLayout, ...
                    fieldStruct);

                components.StartFrame.ValueChangedFcn = @(field,~) startFrameChanged(field.Value);
                components.EndFrame.ValueChangedFcn = @(field,~) endFrameChanged(field.Value);

                function startFrameChanged(val)
                    node.NodeData.startFrame = val;
                    app.saveCurrentAnnotation;
                end

                function endFrameChanged(val)
                    node.NodeData.endFrame = val;
                    app.saveCurrentAnnotation;
                end
            end

            function setPropertiesToDiscrete(node)
                app.AnnotationTypeButtonGroup.SelectedObject = app.DiscreteButton;  

                fieldStruct.TriggerFrame = node.NodeData.triggerFrame;

                components = generateFields( ...
                    app.PredictedPropertiesGridLayout, ...
                        fieldStruct);

                components.TriggerFrame.ValueChangedFcn = @(field,~) triggerFrameChanged(field.Value);

                function triggerFrameChanged(val)
                    node.NodeData.triggerFrame = val;
                    app.saveCurrentAnnotation;
                end
            end

            function components = generateFields(gridLayout, fieldStruct)
                gridLayout.Children.delete;
                fields = fieldnames(fieldStruct);
                len = length(fields);

                for i = 1:len
                    fieldName = fields{i};
                    components.(fieldName) = addFieldAt(i, fieldName, fieldStruct.(fieldName));
                end

                function field = addFieldAt(index, name, defaultValue)
                    label = uilabel(gridLayout);
                    label.HorizontalAlignment = 'right';
                    label.Layout.Row = index;
                    label.Layout.Column = 1;
                    label.Text = name;
        
                    % Create StartFrameEditField
                    field = uieditfield(gridLayout, 'numeric');
                    field.Layout.Row = index;
                    field.Layout.Column = 2;
                    field.Value = defaultValue;
                end
            end
        end

        function updateDemonstrationTab(app)
            app.DemonstrationURL.Visible = "off";
            app.CopyDemonstrationURLToClipboard.Visible = "off";
            app.ViewDemonstrationButton.Visible = "off"
        end

        function updateUnannotatedCapturesTable(app)
            status = app.CaptureStatusDropDown.Value;

            gestureInstances = app.db.getGestureInstances();
            switch status
                case "all"
                case "unannotated and incomplete"
                    gestureInstances = ...
                        gestureInstances( ...
                            strcmp(gestureInstances.annotStatus, "unannotated") | ...
                            strcmp(gestureInstances.annotStatus, "incomplete"), :);
                otherwise
                    gestureInstances = gestureInstances(strcmp(gestureInstances.annotStatus, status), :);
            end
            
            
            app.CapturesTable.Data = gestureInstances;
            count = height(gestureInstances);
            app.CountEditField.Value = count;
        end

        function updateGestureInstanceTable(app)
            [gid, success] = app.getGid();

            if success
                gestureInstances = app.db.getInstancesOfGesture(gid);
                app.GestureInstanceTable.Data = gestureInstances;
            end
        end

        function updateTrialData(app)
            [~, success] = app.getTid();

            if success
                app.TrialDataPanel.Enable = 'on';
                app.updateTrialGesturesTable();
                app.updateTrialPropertiesPanel();
            else
                app.TrialDataPanel.Enable = 'off';
            end
        end

        function updateParticipantGesturesTable(app)
            [pid, success] = app.getPid();

            if success
                gestureInstances = app.db.getGesturesOfParticipant(pid);
                app.ParticipantGesturesTable.Data = gestureInstances;
            end
        end

        function updateTrialGesturesTable(app)
            [tid, success] = app.getTid();

            if success
                gestures = app.db.getGesturesOfTrial(tid);
                app.TrialGesturesTable.Data = gestures;
            end
        end

        function updateTrialPropertiesPanel(app)
            [tid, success] = app.getTid();
    
            if success
                trial = app.db.getTrialFromId(tid);
                app.TrialNameEditField.Value = trial.name;
                app.TrialIDEditField.Value = tid;
                app.TrialInstructionsTextArea.Value = trial.instructions;
            end
        end

        function updateParticipantTrialsTable(app)
            [pid, success] = app.getPid();
            
            if success
                app.ParticipantTrialsTable.Enable = 'on';
            else
                app.ParticipantTrialsTable.Enable = 'off';
                return;
            end

            trials = app.db.getTrialsOfParticipant(pid);
            app.ParticipantTrialsTable.Data = trials;
        end

        function updateTrialTemplateList(app)
            [ids, names] = app.db.getTemplateIdsAndNames();
            app.TrialTemplateList.Items = names;
            app.TrialTemplateList.ItemsData = ids;
        end

        function updateGestureList(app)
            [ids, names] = app.db.getAllGestureIdsAndNames();
            app.GestureList.Items = names;
            app.GestureList.ItemsData = ids;
        end
        
        function updateParticipantList(app)
            ids = app.db.getParticipantIds();
            app.ParticipantList.Items = "Participant: " + ids;
            app.ParticipantList.ItemsData = ids;
        end        
        
        function closeApp(app)
            close(app.GestureLoggerUIFigure);
            delete(app);
        end
            
        
        function updateParticipantMetadata(app)
            app.ParticipantURL.Visible = "off";
            app.CopytoClipboardButton.Visible = "off";

            [pid, success] = app.getPid();
            
            if success
                app.ParticipantInfoTextArea.Value = app.db.getParticipantInfo(pid);
            end
        end
        
        function openGestureAnnotator(app, giid)
            try 
                [exists, annotFile] = app.db.getAnnotFile(giid);
                
                if ~exists
                    gid = app.db.getGidOfGiid(giid);
                    json = app.db.getGestureJson(gid);
                    annotFile = getAnnotFilePath(giid);

                    fileId = fopen(annotFile, 'w');
                    fprintf(fileId, json);
                    fclose(fileId);

                    app.db.addGestureAnnotation(giid, annotFile);
                end

                progressDlg = uiprogressdlg(app.GestureLoggerUIFigure, ...
                    'Title','Opening annotator',...
                    'Message','Please wait, the gesture annotator is opening. It might take some time.', ...
                    'Indeterminate','on');

                dataFile = app.db.getDataFileOfGiid(giid);
                isStream = app.db.getIsStreamOfGiid(giid);
                basicReplayGrid(dataFile, annotFile, isStream, app, giid);
                close(progressDlg);
            catch ME
                disp(ME);
                app.warn("There was an error opening this gesture");
            end

            function name = getAnnotFilePath(giid)
                name = fullfile(app.annotFolder, strcat(string(giid), ".json"));
            end
        end
        
        function openGestureInstanceSafe(app, table)
            [giid, success] = Menu.getGiid(table);
            
            if success
                app.openGestureAnnotator(giid);
            else
                app.warn("You must select a gesture to annotate")
            end
        end
        
        function message = syncUp(app)
            unpushedTrials = app.db.getUnpushedTrialInstances();
            outArr = arrayfun(@(tiid) app.sendTrial(tiid), unpushedTrials);
            
            numTrials = string(numel(unpushedTrials));

            if all(outArr)
                message = strcat("Uploaded ", numTrials, " trials. ");
            elseif any (outArr)
                message = strcat("Attempted to upload ", numTrials, " trials - some unsuccessful. ");
            else
                message = strcat("Failed to upload trials. ");
            end
        end

        function message = syncDown(app)
            % download pending trial instances
            progressDlg = uiprogressdlg(app.GestureLoggerUIFigure, ...
                'Title','Downloading gestures',...
                'Message','Requesting completed trials');
           
            [success, trials] = app.requestManager.getCompletedTrials;

            if ~success
                app.warn("Unable to communicate with server");
                return;
            end

            total = numel(trials);

            % for each completed trial, download it
            for i = 1:total
                currentTrial = trials{i};
                progressDlg.Message = strcat('Downloading gestures of trial: ', currentTrial);
                progressDlg.Value = (i-1) / total;
                downloadGesturesOfTrial(currentTrial);
            end

            progressDlg.Message = 'Finished downloading';
            progressDlg.Value = 1;

            pause(0.5);
            close(progressDlg);
            message = strcat("Downloaded ", string(total), " trials. ");

            function downloadGesturesOfTrial(tiid)
                try
                    [gids, pid, repetitions] = app.db.getGestureInfoInTrialInstance(tiid);
                    numGestures = sum(repetitions);
                    success = false(1, numGestures);

                    index = 1;
                    for j = 1:numel(gids)
                        for k = 1:repetitions(j)
                            [success(index), loc] = app.requestManager.downloadGesture( ...
                                tiid, pid, index - 1, app.dataFolder);

                            if success(index)
                                app.db.addGestureInstance(tiid, gids(j), loc);
                            end         

                            index = index + 1;
                        end
                    end
    
                    if all(success)
                        app.db.updateTrialInstanceStatus(tiid, "downloaded")
                    elseif any(success)
                        app.db.updateTrialInstanceStatus(tiid, "partially downloaded");
                    else
                        app.db.updateTrialInstanceStatus(tiid, "failed");
                    end
                catch ME
                    disp(ME);
                    app.db.updateTrialInstanceStatus(currentTrial, "failed");
                end

                app.requestManager.removeTrial(pid, tiid);
            end
        end
        
        function sync(app)
            upMessage = app.syncUp();
            downMessage = app.syncDown();
            app.inform(strcat(upMessage, newline, downMessage))
            app.updateAll();
        end
        
        function exportShreclike(app)
            exportSHREC(app.db, app.project);
        end
        
        function isRoot = isNodeRootOfGesture(app, node)
            isRoot = node == app.EditGestureTree.Children(1);
        end
    end
    
    methods (Access = public)

        function [gid, success] = gestureSelector(app)
            [gids, names] = app.db.getAllGestureIdsAndNames();
            
            % set defaults
            gid = []; 
            success = false;

            if isempty(gids)
                app.warn("No gestures to choose from")
                return;
            end

            [index, tf] = listdlg( ...
                'ListString', names, ...
                'PromptString', "Choose a gesture", ...
                'SelectionMode', 'single');

            if ~tf
                return
            end

            success = true;
            gid = gids(index);
        end


        function markGestureComplete(app, giid)
            disp(strcat("marking gesture: ", string(giid), " - complete"));
            app.db.updateGestureAnnotationStatus(giid, "complete");
        end

        function markGestureIncomplete(app, giid)
            app.db.updateGestureAnnotationStatus(giid, "incomplete");
        end
        
        function updateAll(app)
            app.updateTrialData();
            app.updateParticipantTrialsTable();
            app.updateTrialTemplateList();
            app.updateGestureList();
            app.updateParticipantList();
            app.updateParticipantGesturesTable();
            app.updateUnannotatedCapturesTable();
            app.updateGestureInstanceTable();
            app.updateDemonstrationTab();
            app.updateEditGestureTab();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project, requestManager, annotFolder, dataFolder)
            app.annotFolder = annotFolder;
            app.dataFolder = dataFolder;

            databaseFile = fullfile(app.dataFolder, strcat(project, ".db"));

            app.db = databaseManager(databaseFile);

            if app.db.isNewDb
                success = app.confirm("Created a new DB. Click OK if this is the first time you open this project. " + ...
                    "Otherwise cancel and choose the correct annotation and data folders");
            
                if ~success
                    delete(requestManager)
                    delete databaseFile
                    app.closeApp();
                    return;
                end
            end

            app.project = project;

            app.requestManager = requestManager;

            app.FrontendURLInfo.Text = app.requestManager.frontendUrl;
            app.BackendURLInfo.Text = app.requestManager.baseUrl;
            app.AnnotationFolderInfo.Text = app.annotFolder;
            app.GestureDataFolderInfo.Text = app.dataFolder;
            app.updateAll();
        end

        % Value changed function: TrialNameEditField
        function TrialNameEditFieldValueChanged(app, event)
            value = app.TrialNameEditField.Value;
            [tid, success]= getTid(app);

            if success
                app.db.updateTrialName(tid, value)
                app.updateTrialPropertiesPanel();
                app.updateTrialTemplateList();
            end
        end

        % Value changed function: TrialInstructionsTextArea
        function TrialInstructionsTextAreaValueChanged(app, event)
            value = app.TrialInstructionsTextArea.Value;
            [tid, success]= getTid(app);

            if success
                app.db.updateTrialInstructions(tid, value)
                app.updateTrialPropertiesPanel();
            end
        end

        % Cell edit callback: TrialGesturesTable
        function TrialGesturesTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            [tid, success] = app.getTid();

            if success
                pos = app.TrialGesturesTable.Data.Properties.RowNames{indices(1)};
                columnName = app.TrialGesturesTable.Data.Properties.VariableNames{indices(2)};
                
    
                app.db.updateGestureInTrial(tid, pos, columnName, newData);
            end
        end

        % Button pushed function: AddGestureToTrialButton
        function AddGestureToTrialButtonPushed(app, event)
            [gid, success] = app.gestureSelector();

            if ~success
                return
            end

            [tid, success] = app.getTid();

            if ~success
                return
            end

            app.db.addGestureToTrial(gid, tid);
            app.updateTrialGesturesTable();
        end

        % Button pushed function: AddTrialButton
        function AddTrialButtonPushed(app, event)
            app.db.addTrialTemplate();
            app.updateTrialTemplateList();
        end

        % Value changed function: TrialTemplateList
        function TrialTemplateListValueChanged(app, event)
            app.updateTrialData();
        end

        % Button pushed function: AddGestureButton
        function AddGestureButtonPushed(app, event)
            annot = continuousAnnotation.default;
            annot.name = "New gesture";

            json = annot.getJson();
            app.db.addGesture(annot.name, json);
            app.updateGestureList();
        end

        % Close request function: GestureLoggerUIFigure
        function GestureLoggerUIFigureCloseRequest(app, event)
            app.closeApp()
        end

        % Button pushed function: AddParticipantButton
        function AddParticipantButtonPushed(app, event)
            app.db.addParticipant();
            app.updateParticipantList();
        end

        % Value changed function: ParticipantList
        function ParticipantListValueChanged(app, event)
            app.updateParticipantTrialsTable();
            app.updateParticipantGesturesTable();
            app.updateParticipantMetadata();
        end

        % Button pushed function: AddTrialToParticipantButton
        function AddTrialToParticipantButtonPushed(app, event)
            [tid, success] = app.trialSelector();
            
            if ~success
                return
            end

            [pid, success] = app.getPid();

            if ~success
                return
            end

            app.db.addTrialToParticipant(pid, tid);
            app.updateParticipantTrialsTable();
        end

        % Double-clicked callback: TrialGesturesTable
        function TrialGesturesTableDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            [tid, success] = app.getTid();

            if ~success
                return
            end

            columnName = app.TrialGesturesTable.Data.Properties.VariableNames{displayColumn};

            if columnName == "name"
                pos = app.TrialGesturesTable.Data.Properties.RowNames{displayRow};
                [gid, success] = app.gestureSelector();

                if success
                    app.db.updateGestureInTrial(tid, pos, "gid", gid);
                    app.updateTrialGesturesTable();
                end
            end
            
        end

        % Button pushed function: ParticipantURLButton
        function ParticipantURLButtonPushed(app, event)
            [pid, pidSuccess] = app.getPid();

            try 
                if pidSuccess
                    [reqSuccess, urlCode] = app.requestManager.getUrl(pid);
                    if reqSuccess
                        url = urlFromURlCode(pid, urlCode);
                        app.ParticipantURL.URL = url;
                        app.ParticipantURL.Text = url;
                        app.ParticipantURL.Visible = "on";
                        app.CopytoClipboardButton.Visible = "on";
                    else
                        warning = strcat( ...
                            "Failed to request a URL from the server. ", ...
                            "Ensure you have pushed a trial to this participant.");
                        app.warn(warning);
                    end
                else
                    warning = strcat("You must select a participant to get a URL");
                    app.warn(warning);
                end
            catch
                warning = strcat( ...
                    "The server is not responding or you do not have a connection");
                app.warn(warning);
            end

            function url = urlFromURlCode(pid, urlCode)
                url = strcat( ...
                    app.requestManager.frontendUrl, ...
                    "?project=", app.project, ...
                    "&participant=", string(pid), "-", urlCode, ...
                    "&", app.getFrontendUrlQueryParam());
            end
        end

        % Menu selected function: RefreshMenu
        function RefreshParticipantsButtonPushed(app, event)
            app.updateAll();
        end

        % Button pushed function: CopytoClipboardButton
        function CopytoClipboardButtonPushed(app, event)
            clipboard("copy", app.ParticipantURL.URL);
        end

        % Value changed function: GestureList
        function GestureListValueChanged(app, event)
            app.updateGestureInstanceTable();
            app.updateDemonstrationTab();
            app.updateEditGestureTab();
        end

        % Button pushed function: OpenParticipantGestureAnnot
        function OpenGestureAnnotatorButtonPushed(app, event)
            app.openGestureInstanceSafe(app.ParticipantGesturesTable)
        end

        % Button pushed function: OpenGestureInstanceButton
        function OpenGestureInstanceButtonPushed(app, event)
            app.openGestureInstanceSafe(app.GestureInstanceTable)
        end

        % Button pushed function: OpenUnannotatedGestureButton
        function OpenUnannotatedGestureButtonPushed2(app, event)
            app.openGestureInstanceSafe(app.CapturesTable)
        end

        % Menu selected function: SyncMenu
        function SyncMenuSelected(app, event)
            app.sync();
        end

        % Menu selected function: ExportSHREClikeMenu
        function ExportSHREClikeMenuSelected(app, event)
            app.exportShreclike;
        end

        % Value changed function: CaptureStatusDropDown
        function CaptureStatusDropDownValueChanged(app, event)
            app.updateUnannotatedCapturesTable();
        end

        % Button pushed function: GetDemonstrationUrlButton
        function GetDemonstrationUrlButtonPushed(app, event)
            [gid, success] = app.getGid();

            if ~success
                app.warn("You must select a gesture to record a demonstration for.");
                return;
            end

            durationS = app.DemonstrationDurationEditField.Value;

            if durationS <= 0
                app.warn("Duration must be greater than 0")
                return;
            end

            [success, shortCode] = app.requestManager.getDemonstrationShortCode(gid);

            if success
                durationMs = durationS * 1000;

                name = app.db.getNameOfGesture(gid);
                url = getUrlFromShortcode(shortCode, durationMs, name);
                app.DemonstrationURL.URL = url;
                app.DemonstrationURL.Text = url;

                app.DemonstrationURL.Visible = "on";
                app.CopyDemonstrationURLToClipboard.Visible = "on";

                demonstrationExists = app.requestManager.demonstrationExists(app.project, gid);

                if demonstrationExists
                    text = "Recording exists - click to view";
                    enable = "on";
                else
                    text = "Recording does not yet exist";
                    enable = "off";
                end

                app.ViewDemonstrationButton.Enable = enable;
                app.ViewDemonstrationButton.Text = text;
                app.ViewDemonstrationButton.Visible = "on";
            else
                app.warn("Unable to get a URL for this demonstration")
                return;
            end

            function url = getUrlFromShortcode(shortCode, durationMs, name)
                url = strcat( ...
                    app.requestManager.frontendUrl, ...
                    "/demonstration/", ...
                    "?code=", shortCode, ...
                    "&durationMs=", string(durationMs), ...
                    "&name=", urlencode(name), ...
                    "&", app.getFrontendUrlQueryParam);
            end
        end

        % Button pushed function: CopyDemonstrationURLToClipboard
        function CopyDemonstrationURLToClipboardButtonPushed(app, event)
            clipboard("copy", app.DemonstrationURL.URL);
        end

        % Selection changed function: EditGestureTree
        function EditGestureTreeSelectionChanged(app, event)
            app.updateAnnotationPropertiesPanel();
        end

        % Value changed function: AnnotationNameEditField
        function AnnotationNameEditFieldValueChanged(app, event)
            value = app.AnnotationNameEditField.Value;
            selectedNodes = app.EditGestureTree.SelectedNodes;

            if ~isempty(selectedNodes)
                selectedNodes.NodeData.name = value;
                app.saveCurrentAnnotation();
                selectedNodes.Text = value;

                if app.isNodeRootOfGesture(selectedNodes)
                    app.updateGestureLevelProperties;
                    app.updateGestureList;
                end
            end
        end

        % Selection changed function: AnnotationTypeButtonGroup
        function AnnotationTypeButtonGroupSelectionChanged(app, event)
            selectedNode = app.EditGestureTree.SelectedNodes;

            if isempty(selectedNode)
                return;
            end

            switch app.AnnotationTypeButtonGroup.SelectedObject
                case app.DiscreteButton
                    confirmMessage = "Changing a continuous annotation to discrete will make it lose all its children. Are you sure you want to continue?";
                    noChildren = isempty(selectedNode.NodeData.children);

                    if noChildren
                        setAnnotationToDiscrete
                    else
                        if app.confirm(confirmMessage)
                            setAnnotationToDiscrete
                            app.updateEditGestureTree
                        else
                            app.AnnotationTypeButtonGroup.SelectedObject = app.ContinuousButton;
                        end
                    end
                case app.ContinuousButton
                    selectedNode.NodeData = continuousAnnotation.from(selectedNode.NodeData);
            end

            app.saveCurrentAnnotation();
            app.updateAnnotationPropertiesPanel();

            function setAnnotationToDiscrete
                selectedNode.NodeData = discreteAnnotation.from(selectedNode.NodeData);
                app.saveCurrentAnnotation();
            end
        end

        % Button pushed function: AddSubAnnotationButton
        function AddSubAnnotationButtonPushed(app, event)
            selectedNode = app.EditGestureTree.SelectedNodes;

            if isempty(selectedNode)
                app.warn("Must select an annotation to add a subannotation to");
                return;
            end

            annot = selectedNode.NodeData;

            if annot.annotType == "continuous"
                annot.addChild(createDefaultNode());
                app.saveCurrentAnnotation();
                app.updateEditGestureTree();
            else
                app.warn("Annotation must be continuous to have children");
            end

            function node = createDefaultNode()
                node = continuousAnnotation.default;
            end
        end

        % Button pushed function: DeleteSubAnnotationButton
        function DeleteSubAnnotationButtonPushed(app, event)
            selectedNodes = app.EditGestureTree.SelectedNodes;

            if app.isNodeRootOfGesture(selectedNodes)
                app.warn("You can not delete the main annotation")
            else
                if ~isempty(selectedNodes)
                    selectedNodes.NodeData.parent.removeChild(selectedNodes.NodeData);
                    app.saveCurrentAnnotation();
                    app.updateEditGestureTree();
                end
            end
        end

        % Value changed function: ParticipantInfoTextArea
        function ParticipantInfoTextAreaValueChanged(app, event)
            value = app.ParticipantInfoTextArea.Value;

            [pid, success] = app.getPid();

            if success
                app.db.updateParticipantInfo(pid, value);
            else
                app.warn("You must select a participant to edit its info")
            end
        end

        % Value changed function: StreamGestureCheckBox
        function StreamGestureCheckBoxValueChanged(app, event)
            value = app.StreamGestureCheckBox.Value;
            
            [gid, success] = app.getGid();
            
            if success
                app.db.updateGestureIsStream(gid, value)
                
                if value
                    name = app.GestureNameEditField.Value;
                    annot = continuousAnnotation(name, 1, 100000);
                    app.db.updateGestureJson(gid, annot.getJson());
                else
                    name = app.GestureNameEditField.Value;
                    annot = continuousAnnotation.default;
                    annot.name = name;
                    app.db.updateGestureJson(gid, annot.getJson());
                end
            end

            app.updateEditGestureTab();
        end

        % Value changed function: GestureNameEditField
        function GestureNameEditFieldValueChanged(app, event)
            value = app.GestureNameEditField.Value;
            [gid, success] = app.getGid();

            if success
                app.db.updateGestureName(gid, value);
                app.updateGestureList;
            end
        end

        % Button pushed function: ViewDemonstrationButton
        function ViewDemonstrationButtonPushed(app, event)
            [gid, success] = app.getGid();
            
            if success
                url = strcat( ...
                    app.requestManager.frontendUrl, ...
                    "/demonstration/view/", ...
                    "?project=", app.project, ...
                    "&gid=", gid);
                web(url, '-browser');
            else
                app.warn("No gesture selected")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GestureLoggerUIFigure = uifigure('Visible', 'off');
            app.GestureLoggerUIFigure.Name = 'Gesture Logger';
            app.GestureLoggerUIFigure.CloseRequestFcn = createCallbackFcn(app, @GestureLoggerUIFigureCloseRequest, true);
            app.RefreshMenu = uimenu(app.GestureLoggerUIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshParticipantsButtonPushed, true);
            app.RefreshMenu.Text = 'Refresh';
            app.SyncMenu = uimenu(app.GestureLoggerUIFigure);
            app.SyncMenu.MenuSelectedFcn = createCallbackFcn(app, @SyncMenuSelected, true);
            app.SyncMenu.Text = 'Sync ⟳';
            app.ExportMenu = uimenu(app.GestureLoggerUIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportSHREClikeMenu = uimenu(app.ExportMenu);
            app.ExportSHREClikeMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSHREClikeMenuSelected, true);
            app.ExportSHREClikeMenu.Text = 'Export SHREC-like';
            app.AdminMenu = uimenu(app.GestureLoggerUIFigure);
            app.AdminMenu.Text = 'Admin';
            app.FrontendURLMenu = uimenu(app.AdminMenu);
            app.FrontendURLMenu.Text = 'Frontend URL';
            app.FrontendURLInfo = uimenu(app.FrontendURLMenu);
            app.FrontendURLInfo.Text = 'unset';
            app.BackendURLMenu = uimenu(app.AdminMenu);
            app.BackendURLMenu.Text = 'Backend URL';
            app.BackendURLInfo = uimenu(app.BackendURLMenu);
            app.BackendURLInfo.Text = 'unset';
            app.GestureDataFolderMenu = uimenu(app.AdminMenu);
            app.GestureDataFolderMenu.Text = 'Gesture Data Folder';
            app.GestureDataFolderInfo = uimenu(app.GestureDataFolderMenu);
            app.GestureDataFolderInfo.Text = 'unset';
            app.AnnotationFolderMenu = uimenu(app.AdminMenu);
            app.AnnotationFolderMenu.Text = 'Annotation Folder';
            app.AnnotationFolderInfo = uimenu(app.AnnotationFolderMenu);
            app.AnnotationFolderInfo.Text = 'unset';
            app.GridLayout = uigridlayout(app.GestureLoggerUIFigure);
            app.AllPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.AllPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GesturesTab = uitab(app.TabGroup);
            app.GesturesTab.Title = 'Gestures';
            app.GridLayout3_2 = uigridlayout(app.GesturesTab);
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.GesturesLabel = uilabel(app.GridLayout4_2);
            app.GesturesLabel.Text = 'Gestures';
            app.GestureList = uilistbox(app.GridLayout4_2);
            app.GestureList.Items = {};
            app.GestureList.ValueChangedFcn = createCallbackFcn(app, @GestureListValueChanged, true);
            app.GestureList.Value = {};
            app.AddGestureButton = uibutton(app.GridLayout4_2, 'push');
            app.AddGestureButton.ButtonPushedFcn = createCallbackFcn(app, @AddGestureButtonPushed, true);
            app.AddGestureButton.Text = 'Add Gesture';
            app.GesturesTabGroup = uitabgroup(app.GridLayout3_2);
            app.EditGestureTab = uitab(app.GesturesTabGroup);
            app.EditGestureTab.Title = 'Edit Gesture';
            app.GridLayout15 = uigridlayout(app.EditGestureTab);
            app.EditGesturePanel = uipanel(app.GridLayout15);
            app.GridLayout13 = uigridlayout(app.EditGesturePanel);
            app.AnnotationPropertiesPanel = uipanel(app.GridLayout13);
            app.AnnotationPropertiesPanel.Title = 'Annotation Properties';
            app.GridLayout14 = uigridlayout(app.AnnotationPropertiesPanel);
            app.AnnotationTypeButtonGroup = uibuttongroup(app.GridLayout14);
            app.AnnotationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnnotationTypeButtonGroupSelectionChanged, true);
            app.AnnotationTypeButtonGroup.Title = 'Annotation Type';
            app.DiscreteButton = uiradiobutton(app.AnnotationTypeButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.ContinuousButton = uiradiobutton(app.AnnotationTypeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.PredictedPropertiesOptionalPanel = uipanel(app.GridLayout14);
            app.PredictedPropertiesOptionalPanel.Title = 'Predicted Properties (Optional)';
            app.PredictedPropertiesGridLayout = uigridlayout(app.PredictedPropertiesOptionalPanel);
            app.StartFrameEditFieldLabel = uilabel(app.PredictedPropertiesGridLayout);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.PredictedPropertiesGridLayout, 'numeric');
            app.EndFrameEditFieldLabel = uilabel(app.PredictedPropertiesGridLayout);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.PredictedPropertiesGridLayout, 'numeric');
            app.AnnotationNameEditFieldLabel = uilabel(app.GridLayout14);
            app.AnnotationNameEditFieldLabel.Text = 'Annotation Name';
            app.AnnotationNameEditField = uieditfield(app.GridLayout14, 'text');
            app.AnnotationNameEditField.ValueChangedFcn = createCallbackFcn(app, @AnnotationNameEditFieldValueChanged, true);
            app.AddSubAnnotationButton = uibutton(app.GridLayout13, 'push');
            app.AddSubAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubAnnotationButtonPushed, true);
            app.AddSubAnnotationButton.Text = 'Add Sub-Annotation';
            app.DeleteSubAnnotationButton = uibutton(app.GridLayout13, 'push');
            app.DeleteSubAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubAnnotationButtonPushed, true);
            app.DeleteSubAnnotationButton.Text = 'Delete Sub-Annotation';
            app.EditGestureTree = uitree(app.GridLayout13);
            app.EditGestureTree.SelectionChangedFcn = createCallbackFcn(app, @EditGestureTreeSelectionChanged, true);
            app.StreamGestureCheckBox = uicheckbox(app.GridLayout15);
            app.StreamGestureCheckBox.ValueChangedFcn = createCallbackFcn(app, @StreamGestureCheckBoxValueChanged, true);
            app.StreamGestureCheckBox.Text = 'This is a stream gesture';
            app.GestureNameEditFieldLabel = uilabel(app.GridLayout15);
            app.GestureNameEditFieldLabel.Text = 'Gesture Name:';
            app.GestureNameEditField = uieditfield(app.GridLayout15, 'text');
            app.GestureNameEditField.ValueChangedFcn = createCallbackFcn(app, @GestureNameEditFieldValueChanged, true);
            app.InstancesTab = uitab(app.GesturesTabGroup);
            app.InstancesTab.Title = 'Instances';
            app.GridLayout6 = uigridlayout(app.InstancesTab);
            app.GestureInstanceLabel = uilabel(app.GridLayout6);
            app.GestureInstanceLabel.Text = 'Gesture Instance';
            app.OpenGestureInstanceButton = uibutton(app.GridLayout6, 'push');
            app.OpenGestureInstanceButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGestureInstanceButtonPushed, true);
            app.OpenGestureInstanceButton.Text = 'Open Gesture Annotator';
            app.GestureInstanceTable = uitable(app.GridLayout6);
            app.GestureInstanceTable.ColumnName = {'InstanceID'; 'ParticipantID'; 'Annot Status'};
            app.GestureInstanceTable.RowName = {};
            app.GestureInstanceTable.ColumnSortable = true;
            app.GestureInstanceTable.SelectionType = 'row';
            app.GestureInstanceTable.Multiselect = 'off';
            app.DemonstrationTab = uitab(app.GesturesTabGroup);
            app.DemonstrationTab.Title = 'Demonstration';
            app.GridLayout12 = uigridlayout(app.DemonstrationTab);
            app.GetDemonstrationUrlButton = uibutton(app.GridLayout12, 'push');
            app.GetDemonstrationUrlButton.ButtonPushedFcn = createCallbackFcn(app, @GetDemonstrationUrlButtonPushed, true);
            app.GetDemonstrationUrlButton.Text = 'Get Demonstration Recording URL';
            app.DurationsecondsLabel = uilabel(app.GridLayout12);
            app.DurationsecondsLabel.Text = 'Duration (seconds)';
            app.DemonstrationDurationEditField = uieditfield(app.GridLayout12, 'numeric');
            app.DemonstrationDurationEditField.Value = 1.5;
            app.DemonstrationURL = uihyperlink(app.GridLayout12);
            app.DemonstrationURL.Visible = 'off';
            app.DemonstrationURL.Text = 'Not yet fetched';
            app.CopyDemonstrationURLToClipboard = uibutton(app.GridLayout12, 'push');
            app.CopyDemonstrationURLToClipboard.ButtonPushedFcn = createCallbackFcn(app, @CopyDemonstrationURLToClipboardButtonPushed, true);
            app.CopyDemonstrationURLToClipboard.Visible = 'off';
            app.CopyDemonstrationURLToClipboard.Text = 'Copy to Clipboard';
            app.DemonstrationStatusLabel = uilabel(app.GridLayout12);
            app.DemonstrationStatusLabel.Visible = 'off';
            app.DemonstrationStatusLabel.Text = 'Demonstration Status';
            app.ViewDemonstrationButton = uibutton(app.GridLayout12, 'push');
            app.ViewDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDemonstrationButtonPushed, true);
            app.ViewDemonstrationButton.Text = 'View Demonstration';
            app.TrialTemplateTab = uitab(app.TabGroup);
            app.TrialTemplateTab.Title = 'Trial Template';
            app.GridLayout3_3 = uigridlayout(app.TrialTemplateTab);
            app.GridLayout4_3 = uigridlayout(app.GridLayout3_3);
            app.TrialTemplatesLabel = uilabel(app.GridLayout4_3);
            app.TrialTemplatesLabel.Text = 'Trial Templates';
            app.TrialTemplateList = uilistbox(app.GridLayout4_3);
            app.TrialTemplateList.Items = {};
            app.TrialTemplateList.ValueChangedFcn = createCallbackFcn(app, @TrialTemplateListValueChanged, true);
            app.TrialTemplateList.Value = {};
            app.AddTrialButton = uibutton(app.GridLayout4_3, 'push');
            app.AddTrialButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialButtonPushed, true);
            app.AddTrialButton.Text = 'Add Trial';
            app.TrialDataPanel = uipanel(app.GridLayout3_3);
            app.GridLayout7 = uigridlayout(app.TrialDataPanel);
            app.AddGestureToTrialButton = uibutton(app.GridLayout7, 'push');
            app.AddGestureToTrialButton.ButtonPushedFcn = createCallbackFcn(app, @AddGestureToTrialButtonPushed, true);
            app.AddGestureToTrialButton.Text = 'Add Gesture';
            app.TrialGesturesPanel = uipanel(app.GridLayout7);
            app.TrialGesturesPanel.Title = 'Gestures';
            app.GridLayout9 = uigridlayout(app.TrialGesturesPanel);
            app.TrialGesturesTable = uitable(app.GridLayout9);
            app.TrialGesturesTable.ColumnName = {'Name'; 'Instruction'; 'Duration (s)'; 'Repetitions'};
            app.TrialGesturesTable.RowName = {};
            app.TrialGesturesTable.ColumnSortable = true;
            app.TrialGesturesTable.SelectionType = 'row';
            app.TrialGesturesTable.ColumnEditable = [false true true true];
            app.TrialGesturesTable.CellEditCallback = createCallbackFcn(app, @TrialGesturesTableCellEdit, true);
            app.TrialGesturesTable.DoubleClickedFcn = createCallbackFcn(app, @TrialGesturesTableDoubleClicked, true);
            app.TrialGesturesTable.Multiselect = 'off';
            app.TrialPropertiesPanel = uipanel(app.GridLayout7);
            app.TrialPropertiesPanel.Title = 'Properties';
            app.GridLayout8 = uigridlayout(app.TrialPropertiesPanel);
            app.NameEditFieldLabel = uilabel(app.GridLayout8);
            app.NameEditFieldLabel.Text = 'Name';
            app.TrialNameEditField = uieditfield(app.GridLayout8, 'text');
            app.TrialNameEditField.ValueChangedFcn = createCallbackFcn(app, @TrialNameEditFieldValueChanged, true);
            app.TrialIDEditFieldLabel = uilabel(app.GridLayout8);
            app.TrialIDEditFieldLabel.Enable = 'off';
            app.TrialIDEditFieldLabel.Text = 'TrialID';
            app.TrialIDEditField = uieditfield(app.GridLayout8, 'text');
            app.TrialIDEditField.Enable = 'off';
            app.InstructionsTextAreaLabel = uilabel(app.GridLayout8);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.TrialInstructionsTextArea = uitextarea(app.GridLayout8);
            app.TrialInstructionsTextArea.ValueChangedFcn = createCallbackFcn(app, @TrialInstructionsTextAreaValueChanged, true);
            app.ParticipantsTab = uitab(app.TabGroup);
            app.ParticipantsTab.Title = 'Participants';
            app.GridLayout3 = uigridlayout(app.ParticipantsTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ParticipantsLabel = uilabel(app.GridLayout4);
            app.ParticipantsLabel.Text = 'Participants';
            app.ParticipantList = uilistbox(app.GridLayout4);
            app.ParticipantList.Items = {};
            app.ParticipantList.ValueChangedFcn = createCallbackFcn(app, @ParticipantListValueChanged, true);
            app.ParticipantList.Value = {};
            app.AddParticipantButton = uibutton(app.GridLayout4, 'push');
            app.AddParticipantButton.ButtonPushedFcn = createCallbackFcn(app, @AddParticipantButtonPushed, true);
            app.AddParticipantButton.Text = 'Add Participant';
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.TrialsTab = uitab(app.TabGroup2);
            app.TrialsTab.Title = 'Trials';
            app.GridLayout5 = uigridlayout(app.TrialsTab);
            app.AddTrialToParticipantButton = uibutton(app.GridLayout5, 'push');
            app.AddTrialToParticipantButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialToParticipantButtonPushed, true);
            app.AddTrialToParticipantButton.Text = 'Add Trial';
            app.ParticipantTrialsTable = uitable(app.GridLayout5);
            app.ParticipantTrialsTable.ColumnName = {'Trial Instance ID'; 'Name'; 'Status'};
            app.ParticipantTrialsTable.RowName = {};
            app.ParticipantTrialsTable.ColumnSortable = true;
            app.PerformedGesturesTab = uitab(app.TabGroup2);
            app.PerformedGesturesTab.Title = 'Performed Gestures';
            app.GridLayout5_2 = uigridlayout(app.PerformedGesturesTab);
            app.OpenParticipantGestureAnnot = uibutton(app.GridLayout5_2, 'push');
            app.OpenParticipantGestureAnnot.ButtonPushedFcn = createCallbackFcn(app, @OpenGestureAnnotatorButtonPushed, true);
            app.OpenParticipantGestureAnnot.Text = 'Open Gesture Annotator';
            app.ParticipantGesturesTable = uitable(app.GridLayout5_2);
            app.ParticipantGesturesTable.ColumnName = {'Class'; 'Trial Instance ID'; 'Gesture Instance ID'; 'Annotation Status'};
            app.ParticipantGesturesTable.RowName = {};
            app.ParticipantGesturesTable.ColumnSortable = true;
            app.ParticipantGesturesTable.SelectionType = 'row';
            app.ParticipantGesturesTable.Multiselect = 'off';
            app.ParticipantDataTab = uitab(app.TabGroup2);
            app.ParticipantDataTab.Title = 'Participant Data';
            app.GridLayout11 = uigridlayout(app.ParticipantDataTab);
            app.ParticipantURLButton = uibutton(app.GridLayout11, 'push');
            app.ParticipantURLButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantURLButtonPushed, true);
            app.ParticipantURLButton.Text = 'Get URL of Participant';
            app.ParticipantURL = uihyperlink(app.GridLayout11);
            app.ParticipantURL.Visible = 'off';
            app.ParticipantURL.Text = 'Not yet fetched';
            app.CopytoClipboardButton = uibutton(app.GridLayout11, 'push');
            app.CopytoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoClipboardButtonPushed, true);
            app.CopytoClipboardButton.Visible = 'off';
            app.CopytoClipboardButton.Text = 'Copy to Clipboard';
            app.AdditionalParticipantInformationroughnotespaceLabel = uilabel(app.GridLayout11);
            app.AdditionalParticipantInformationroughnotespaceLabel.Text = 'Additional Participant Information (rough note space)';
            app.ParticipantInfoTextArea = uitextarea(app.GridLayout11);
            app.ParticipantInfoTextArea.ValueChangedFcn = createCallbackFcn(app, @ParticipantInfoTextAreaValueChanged, true);
            app.CapturesTab = uitab(app.TabGroup);
            app.CapturesTab.Title = 'Captures';
            app.GridLayout3_4 = uigridlayout(app.CapturesTab);
            app.GridLayout4_4 = uigridlayout(app.GridLayout3_4);
            app.CapturesLabel = uilabel(app.GridLayout4_4);
            app.CapturesLabel.Text = 'Captures';
            app.CapturesTable = uitable(app.GridLayout4_4);
            app.CapturesTable.ColumnName = {'Gesture name'; 'Participant ID'; 'Gesture Instance ID'; 'Anotation Status'};
            app.CapturesTable.RowName = {};
            app.CapturesTable.ColumnSortable = true;
            app.CapturesTable.SelectionType = 'row';
            app.CapturesTable.Multiselect = 'off';
            app.OpenUnannotatedGestureButton = uibutton(app.GridLayout4_4, 'push');
            app.OpenUnannotatedGestureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenUnannotatedGestureButtonPushed2, true);
            app.OpenUnannotatedGestureButton.Text = 'Open Gesture Annotator';
            app.CountEditFieldLabel = uilabel(app.GridLayout4_4);
            app.CountEditFieldLabel.Text = 'Count:';
            app.CountEditField = uieditfield(app.GridLayout4_4, 'numeric');
            app.CountEditField.Editable = 'off';
            app.CaptureStatusDropDownLabel = uilabel(app.GridLayout4_4);
            app.CaptureStatusDropDownLabel.Text = 'Capture Status';
            app.CaptureStatusDropDown = uidropdown(app.GridLayout4_4);
            app.CaptureStatusDropDown.Items = {'unannotated', 'incomplete', 'unannotated and incomplete', 'complete', 'all'};
            app.CaptureStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @CaptureStatusDropDownValueChanged, true);
            app.CaptureStatusDropDown.Value = 'unannotated and incomplete';
            % Show the figure after all components are created
            app.GestureLoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureLoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureLoggerUIFigure)
        end
    end
end