classdef basicReplayGrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureAnnotatorUIFigure        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        RemoveGestureButton             matlab.ui.control.Button
        AddGestureButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        GridLayout3                     matlab.ui.container.GridLayout
        SaveMarkCompleteandCloseButton  matlab.ui.control.Button
        MarkIncompleteButton            matlab.ui.control.Button
        MarkCompleteButton              matlab.ui.control.Button
        SpeedSpinnerLabel_2             matlab.ui.control.Label
        SaveAnnotation                  matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        SpeedSpinner                    matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        Timeline                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        leftHand 
        rightHand 
        handData
        playingAnimation = false
        speedupAmount = 1 
        isRunning = false
        currentFrame
        useRealTime = true
        endFrame = 1

        lineWidth
        mouseDown = false
        mouseButton = 1
        shiftPressed = false
        altPressed = false
        cursor
        cursorSelected = true

        rootAnnotation
        previousSelected 
        isStream = false

        annotFile
        caller
        
        giid = "unassigned"
        needsSave = false;
        baseTitle
    end

    methods (Static)

        function annot = annotFromJson(json)
            struct = jsondecode(json);
            annot = annotFromStruct(struct);

            function annot = annotFromStruct(struct)
                switch struct.annotType
                    case "discrete"
                        annot = discreteAnnotation(struct.name, ...
                                               struct.triggerFrame);
                    case "continuous"
                        annot = continuousAnnotation(struct.name, ...
                                                     struct.startFrame, ...
                                                     struct.endFrame);
                        
                        if ~iscell(struct.children)
                            struct.children = num2cell(struct.children);
                        end

                        numChildren = height(struct.children);
                        children = cell(numChildren, 1);

                        for i = 1:numChildren
                            children{i} = annotFromStruct(struct.children{i});
                        end
    
                        annot.setChildren(children);
                end
            end
        end

        function rootAnnotation = getTemporaryAnnotation()

            rockTrigger = discreteAnnotation("Rock Reveal", 100);
            paperTrigger = discreteAnnotation("Paper Reveal", 400);
            scissorsTrigger = discreteAnnotation("Scissors Reveal", 700);

            rock = continuousAnnotation("Rock", 1, 200);
            rock.setChildren({rockTrigger});
            paper = continuousAnnotation("Paper", 300, 500);
            paper.setChildren({paperTrigger});
            scissors = continuousAnnotation("Scissors", 600, 800);
            scissors.setChildren({scissorsTrigger});

            rockPaperScissors = continuousAnnotation("Rock Paper Scissors", 1, 1200);
            rockPaperScissors.setChildren({rock, paper, scissors});

            rockPaperScissors.getJson()
            rootAnnotation = rockPaperScissors;
        end

        function [mx, my, mz] = getSmallestBoxOfRatio(ratio, x, y, z)
            function [x, y, z] = getCenter(mx, my, mz)
                midpoint = @(mu) (mu(1) + mu(2)) / 2;
                x = midpoint(mx);
                y = midpoint(my);
                z = midpoint(mz);
            end

            function [rx, ry, rz] = getRange(mx, my, mz)
                range = @(mu) mu(2) - mu(1);
                rx = range(mx);
                ry = range(my);
                rz = range(mz);
            end
    
            function [m] = centeredLine(c, r)
                m = [(c - r/2) (c + r/2)];
            end
    
            [mx, my, mz] = basicReplayGrid.getMinimalBox(x, y, z);
            [cx, cy, cz] = getCenter(mx, my, mz);
            [rx, ry, rz] = getRange(mx, my, mz);

            % fix xy ratio
            desiredXYRatio = ratio(1) / ratio(2);
            currentXYRatio = rx / ry;

            if currentXYRatio > desiredXYRatio
                % we cannot decrease x, so we increase y
                ry = rx / desiredXYRatio;
                my = centeredLine(cy, ry);
            elseif currentXYRatio < desiredXYRatio
                % increase x
                rx = ry * desiredXYRatio;
                mx = centeredLine(cx, rx);
            end

            %fix yz plane
            desiredYZRatio = ratio(2) / ratio(3);
            currentYZRatio = ry / rz;

            if currentYZRatio > desiredYZRatio
                % we cannot decrease y, so we increase z
                rz = ry / desiredYZRatio;
                mz = centeredLine(cz, rz);
            elseif currentYZRatio < desiredYZRatio
                % increase y
                ry = rz * desiredYZRatio;
                my = centeredLine(cy, ry);
            end
        end

        function [bx, by, bz] = getMinimalBox(x, y, z)
            bx = [min(x) max(x)];
            by = [min(y) max(y)];
            bz = [min(z) max(z)];
        end

        function [allPoints] = getAllPoints(allHandData)
            allJointLayout = ...
                [basicReplayGrid.prepend("l", 0:24), basicReplayGrid.prepend("r", 0:24)];
            groupData = basicReplayGrid.getGroupData(allHandData, allJointLayout);

            allPoints = structfun( ...
                @(uData) reshape(uData, 1, []), ...
                groupData, UniformOutput=false);
        end

        function [handObj] = updateHandPlot(handObj, t)
            function [groupPlotData] = updateGroup(jointGroup, groupPlotData)
                groupPlotData.XData = jointGroup.x(t, :);
                groupPlotData.YData = jointGroup.y(t, :);
                groupPlotData.ZData = jointGroup.z(t, :);
            end

            for i = 1:numel(handObj.plotData)
                handObj.plotData{i} = updateGroup(handObj.groupData{i}, handObj.plotData{i});
            end
        end
        
        function [modifiedArr] = prepend(val, strArr)
                modifiedArr = arrayfun(@(s) val+int2str(s), strArr);
        end

        function [modifiedHand] = getModifiedHand(handLayout, modification)
            modifiedHand = cellfun(@(arr) basicReplayGrid.prepend(modification, arr), ...
                handLayout, UniformOutput=false);
        end

        function [groupData] = getGroupData(allHandData, jointGroup)
            function [modified] = append_basis(original, basis)
                modified = arrayfun(@(str) str+"_pos_"+basis, original);
            end
        
            xJoints = append_basis(jointGroup, "x");
            yJoints = append_basis(jointGroup, "y");
            zJoints = append_basis(jointGroup, "z");
        
            groupData.x = allHandData{:, xJoints};
            groupData.y = allHandData{:, yJoints};
            groupData.z = allHandData{:, zJoints};
        end
        
        function [clamped] = clamp(val, lb, ub)
            clamped = min(ub, max(lb, val));
        end

        function [allGroupData] = getAllGroupData(allHandData, layout)
            allGroupData = ...
                cellfun(@(jointGroup) basicReplayGrid.getGroupData(allHandData, jointGroup), ...
                layout, UniformOutput=false);
        end
    end
    
    methods (Access = private)

        function warn(app, message)
            uialert( ...
                app.GestureAnnotatorUIFigure, ...
                message, ...
                'Warning', ...
                'Icon', 'warning');
        end

        function isValid = isCallerValid(app)
            isValid = ~isempty(app.caller) && isvalid(app.caller);
        end

        function initCursor(app)
            app.cursor.width = app.endFrame / 40;
            app.cursor.rect = rectangle(app.Timeline, ...
                'Position', [-app.cursor.width / 2, 0.85, app.cursor.width, 1], ...
                'Curvature', [0.9, 1], ...
                'FaceColor', "k");
            app.cursor.line = xline(app.Timeline, 0, ...
                'LineWidth', 2, ...
                'Color', "k", ...
                'Alpha', 1);
        end

        function moveCursorObjTo(app, frame)
            app.cursor.rect.Position(1) = frame - app.cursor.width / 2;
            app.cursor.line.Value = frame;
        end

        function setNeedsSave(app)
            app.needsSave = true;
            app.updateTitle;
        end


        function saveAnnotation(app, fileName)
            jsonString = app.rootAnnotation.getJson();
            writelines(jsonString, fileName);

            app.needsSave = false;
            app.updateTitle;
        end

        function [validFrame] = toValidFrame(app, x)
            validFrame = basicReplayGrid.clamp(round(x), 1, app.endFrame);
        end

        function [validFrame] = isValidFrame(app, frame)
            validFrame = 1 <= frame && frame <= height(app.handData);
        end

        function selectClosest(app, x, y)
            currentClosest.node = app.Tree.Children(1);
            currentClosest.dist = app.rootAnnotation.distanceTo(x, y);

            setClosest(app.Tree.Children(1), x, y);

            function setClosest(node, x, y)
                dist = node.NodeData.distanceTo(x, y);

                if (dist <= currentClosest.dist)
                    currentClosest.dist = dist;
                    currentClosest.node = node;
                end
                
                arrayfun(@(childNode) setClosest(childNode, x, y), node.Children);
            end
            
            app.Tree.SelectedNodes = currentClosest.node;
            app.updateAnnotationsToSelection();
        end

        function loadDataUI(app)
            [filename, path] = uigetfile('*.csv');  %open a mat file

            if (isequal(filename, 0)) 
                return; 
            end

            loadData(app, [path filename], filename);
        end

        function loadData(app, file, fig_title)
            app.handData = readtable(file);
            app.endFrame = height(app.handData);
            title(app.UIAxes, "Displaying: " + fig_title, Interpreter="none");
        end

        function updateToFrame(app, frame)
            app.currentFrame = frame;

            % update hands
            app.leftHand = basicReplayGrid.updateHandPlot(app.leftHand, app.currentFrame);
            app.rightHand = basicReplayGrid.updateHandPlot(app.rightHand, app.currentFrame);
            app.FrameSpinner.Value = app.currentFrame;

            % update cursor
            app.moveCursorObjTo(frame);

            % update if in annot
            updateIfInAnnot(app);

            drawnow limitrate;
        end

        % quite slow, though not worth speeding up yet
        function updateIfInAnnot(app)
            updateNode(app.Tree.Children(1));

            function updateNode(treeNode)
                if treeNode.NodeData.containsFrame(app.currentFrame)
                    treeNode.Text = "⚫ " + treeNode.NodeData.name;
                else
                    treeNode.Text = "⚪ " + treeNode.NodeData.name;
                end

                arrayfun(@updateNode, treeNode.Children);
            end
        end

        function stopPlayback(app) 
            app.isRunning = false;
            app.PlayButton.Icon = "icons/play-solid.svg";
            app.PlayButton.Text = "Play";
        end

        function startPlayback(app, endAt)
            app.PlayButton.Text = "Pause";
            app.PlayButton.Icon = "icons/pause-solid.svg";
            endAt = basicReplayGrid.clamp(endAt, 1, app.endFrame);
            
            % variable to help with responsiveness of interrupting callback
            % funcs. Increasing will increase jitter, decreasing will
            % increase latency
            catchupTime = 0.015;  

            app.isRunning = true;
            tic;  %start timer
            startFrame = app.currentFrame;
            startTime = app.handData{startFrame, "startTime"};
            
            for frame = startFrame:endAt  
                if app.useRealTime
                    time = app.handData{frame, "startTime"};
                    timeToPause = ((time - startTime) / app.speedupAmount) - toc;

                    % skip extra frames if behind real time
                    if timeToPause < 0  
                        continue
                    end

                    % pause if ahead of real time by substantial amount
                    if timeToPause >= catchupTime 
                        pause(timeToPause);
                    end
                end

                if (~app.isRunning)
                    return;
                end

                updateToFrame(app, frame);
            end

            app.stopPlayback();
            if app.currentFrame == app.endFrame
                updateToFrame(app, 1);
            end
            
        end

        function initialiseHands(app)
            % define hand layout
            handLayout = {
                [0 1 2 3 4]
                [0 5 6 7 8 9]
                [0 10 11 12 13 14]
                [0 15 16 17 18 19]
                [0 20 21 22 23 24]
                [0 1 2 6 11 16 21 20 0]
            };

            markers = {
                "b-o" "b-o" "b-o" "b-o" "b-o" "r-o"
            };

            app.leftHand.markers = markers;
            app.rightHand.markers = markers;
            
            app.leftHand.layout = app.getModifiedHand(handLayout, "l");
            app.rightHand.layout = app.getModifiedHand(handLayout, "r");
            
            app.leftHand.groupData = app.getAllGroupData(app.handData, app.leftHand.layout);
            app.rightHand.groupData = app.getAllGroupData(app.handData, app.rightHand.layout);
            
            app.leftHand.plotData = initialiseHandPlot(app, app.leftHand);
            app.rightHand.plotData = initialiseHandPlot(app, app.rightHand);
            
        end

        function initialiseAxes(app)
            % label axes
            % xlabel(app.UIAxes, 'x (m)');
            % ylabel(app.UIAxes, 'y (m)');
            % zlabel(app.UIAxes, 'z (m)');

            hold(app.UIAxes, "on");
            axis(app.UIAxes, "equal");
            axis(app.UIAxes, "manual");
            grid(app.UIAxes, "on");
        end

        function [plotData] = initialiseHandPlot(app, handObj)
            function [groupPlotData] = plotGroup(jointGroup, marker)
                 groupPlotData = plot3(app.UIAxes, ...
                                       jointGroup.x(1, :), ...
                                       jointGroup.y(1, :), ...
                                       jointGroup.z(1, :), ...
                                       marker);
            end
            
            numParts = numel(handObj.groupData);
            plotData = cell([numParts 1]);

            for part = 1:numParts
                plotData{part} = plotGroup(handObj.groupData{part}, handObj.markers{part});
            end
        end

        function moveCursor(app, x)
            stopPlayback(app);
            frame = toValidFrame(app, x);

            if (app.currentFrame ~= frame) 
                updateToFrame(app, frame);
            end
        end

        function handleDragEvent(app, event)
            handleDrag(app, event.Source.SelectionType, true);
        end

        function handleDrag(app, buttonMode, fromDrag)
            if ~app.mouseDown
                return
            end

            mousePointer = get(app.Timeline, "CurrentPoint");
            coord = mousePointer(1, [1, 2]);
            
            % check if within timeline box
            x = coord(1);
            y = coord(2);

            if fromDrag
                buffer = 0.1 * app.endFrame;
            else
                buffer = 0;
            end
            

            if y < 0 || y > 1 || x < -buffer || x > app.endFrame + buffer
                return
            else
                if app.altPressed || buttonMode == "extend"
                    selectClosest(app, x, y);
                else
                    drag();
                end
            end

            function [] = drag()
                moveCursor(app, x);
    
                if app.shiftPressed || buttonMode == "alt"
                    validFrame = toValidFrame(app, x);
                    app.Tree.SelectedNodes.NodeData.drag(validFrame);
                    app.setNeedsSave();
                end
            end
        end

        function handleButtonDown(app, event)
            app.mouseDown = true;
            handleDrag(app, event.Source.SelectionType, false);
        end

        function handleButtonUp(app)
            app.mouseDown = false;
        end

        function initialiseTimeline(app)
            % initialise callbacks
            app.GestureAnnotatorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @handleDragEvent, true);
            app.GestureAnnotatorUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @handleButtonDown, true);
            app.GestureAnnotatorUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @handleButtonUp, false);

            % initialise axes
            app.lineWidth = app.endFrame / 500;
            axis(app.Timeline, [1 app.endFrame 0 1])

            % initialise cursor
            app.initCursor();
        end

        function annot = sanitisedAnnotFromJson(~, annotString)
            annot = basicReplayGrid.annotFromJson(annotString);
        end
    
        function initialiseAnnot(app)
            file = fopen(app.annotFile);
            annotString = fscanf(file,'%c');
            fclose(file);
            app.rootAnnotation = basicReplayGrid.annotFromJson(annotString);
            
            app.updateAnnotationToRoot();
        end

        function refreshRootAnnotation(app)
            app.Tree.Children.delete;
                updateAnnotationToRoot(app);
            app.updateIfInAnnot();
            app.setNeedsSave();
        end

        function updateAnnotationToRoot(app)
            % initialise tree (some redundant code here, consider refactor)
            populateTree(app.Tree, app.rootAnnotation);
            app.Tree.SelectedNodes = app.Tree.Children(1);
            app.previousSelected = app.Tree.SelectedNodes;
            app.previousSelected.NodeData.setSelected(true);

            app.setAnnotationSubtitle(app.previousSelected.NodeData);

            app.updateAnnotationsToSelection();
            expand(app.Tree, "all");

            function populateTree(parent, annotation)
                node = uitreenode(parent, "Text", annotation.name, "NodeData", annotation);
                app.Tree.CheckedNodes = [app.Tree.CheckedNodes; node];

                cellfun(@(annot) populateTree(node, annot), annotation.children);
            end

            % initialise rectangle annot
            app.rootAnnotation.drawAnnot(app.Timeline, 1);
            app.rootAnnotation.clampOwnBounds(1, app.endFrame);
            app.rootAnnotation.updateAnnot();
        end
        
        function updateAnnotationsToSelection(app)
            selectedNodes = app.Tree.SelectedNodes;
            app.previousSelected.NodeData.setSelected(false);
            selectedNodes.NodeData.setSelected(true);
            app.previousSelected = selectedNodes;

            app.setAnnotationSubtitle(selectedNodes.NodeData);
        end

        function setAnnotationSubtitle(app, annot)
            subtitle(app.Timeline, strcat("Annotation: ", annot.name, " (", annot.annotType, ")"));
        end
        
        function updateTitle(app)
            newTitle = app.baseTitle;

            if app.needsSave
                newTitle = strcat(newTitle, "* (unsaved)");
            else
                newTitle = strcat(newTitle, " (saved)");
            end

            app.GestureAnnotatorUIFigure.Name = newTitle;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataFile, annotFile, isStream, caller, giid)
            app.annotFile = annotFile;
            
            if exist("caller", 'var') && exist("giid", 'var')
                app.caller = caller;
                app.giid = giid;

                app.MarkCompleteButton.Enable = 'on';
                app.MarkIncompleteButton.Enable = 'on';
            end

            if exist("isStream", 'var')
                app.isStream = isStream;

                if isStream
                    app.AddGestureButton.Visible = "on";
                    app.RemoveGestureButton.Visible = "on";
                end
            else
                app.isStream = false;
            end

            if exist("dataFile", 'var')
                app.baseTitle = strcat("Gesture Logger - ", dataFile);

                figTitle = strcat("gesture with id ", string(app.giid));
                loadData(app, dataFile, figTitle);
                app.updateTitle;
            else
                loadDataUI(app);
            end
            
            % order of these initialisations matter
            initialiseAnnot(app);

            initialiseAxes(app);

            initialiseHands(app);

            initialiseTimeline(app);

            % set axis limits
            aspectRatio = [1.5, 1, 1];
            allPoints = basicReplayGrid.getAllPoints(app.handData);
            [xlims, ylims, zlims] = basicReplayGrid.getSmallestBoxOfRatio( ...
                aspectRatio, allPoints.x, allPoints.y, allPoints.z);

            axis(app.UIAxes, [xlims ylims zlims]);

            updateToFrame(app, 1);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.isRunning
                stopPlayback(app);
            else
                startPlayback(app, app.endFrame);
            end
        end

        % Value changed function: FrameSpinner
        function FrameValueChanged(app, event)
            value = app.FrameSpinner.Value;
            
            if isValidFrame(app, value)
                stopPlayback(app);
                updateToFrame(app, value);
            else
                app.FrameSpinner.Value = app.currentFrame;
            end
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            app.speedupAmount = app.SpeedSpinner.Value;
        end

        % Window key press function: GestureAnnotatorUIFigure
        function GestureAnnotatorUIFigureWindowKeyPress(app, event)
            key = event.Key;

            if app.shiftPressed
                moveAmount = 10;
            else
                moveAmount = 1;
            end

            switch key
                case "rightarrow"
                    if app.currentFrame < app.endFrame
                        moveCursor(app, app.currentFrame + moveAmount);
                    end
                case "leftarrow"
                    if 0 < app.currentFrame
                        moveCursor(app, app.currentFrame - moveAmount);
                    end
                case "shift"
                    app.shiftPressed = true;
                case "alt"
                    app.altPressed = true;
            end
        end

        % Window key release function: GestureAnnotatorUIFigure
        function GestureAnnotatorUIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case "shift"
                    app.shiftPressed = false;
                case "alt"
                    app.altPressed = false;
                case "space"
                    PlayButtonPushed(app);
                case "k"
                    PlayButtonPushed(app);
                case "a"
                    app.Tree.SelectedNodes.NodeData.drag(app.currentFrame);
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            setVisibility(app.rootAnnotation, false);
            arrayfun(@(node) node.NodeData.setVisibility(true), checkedNodes);

            function setVisibility(annot, visible)
                annot.setVisibility(visible);

                cellfun(@(child) setVisibility(child, visible), ...
                    annot.children);
            end
        end

        % Button pushed function: SaveAnnotation
        function SaveAnnotationPushed(app, event)
            app.saveAnnotation(app.annotFile);
            uialert(app.GestureAnnotatorUIFigure, "Annotation saved", ...
                'Success', 'Icon', 'success');
        end

        % Button pushed function: MarkCompleteButton
        function MarkCompleteButtonPushed(app, event)
            app.caller.markGestureComplete(app.giid);
            uialert( ...
                app.GestureAnnotatorUIFigure, ...
                "Gesture marked as complete", ...
                'Success', ...
                'Icon', 'success');
        end

        % Close request function: GestureAnnotatorUIFigure
        function GestureAnnotatorUIFigureCloseRequest(app, event)
            if app.needsSave
                selection = uiconfirm(app.GestureAnnotatorUIFigure, ...
                    "You have unsaved changes", ...
                    "Close GestureAnnotator?", ...
                    "Options", {'Cancel', 'Close without Saving', 'Save and Close'}, ...
                    "DefaultOption", 3, ...
                    "CancelOption", 1, ...
                    "Icon", "question");

                switch selection
                    case "Cancel"
                        return;
                    case "Close without Saving"
                        closeApp();
                    case "Save and Close"
                        app.saveAnnotation(app.annotFile);
                        closeApp();
                        return;
                end
            else
                closeApp();
            end

            function closeApp()

                if app.isCallerValid()
                    app.caller.updateAll();
                end
    
                delete(app)
            end
        end

        % Button pushed function: MarkIncompleteButton
        function MarkIncompleteButtonPushed(app, event)
            app.caller.markGestureIncomplete(app.giid);
            uialert( ...
                app.GestureAnnotatorUIFigure, ...
                "Gesture marked as incomplete", ...
                'Success', ...
                'Icon', 'success');
        end

        % Clicked callback: Tree
        function TreeClicked(app, event)
            app.updateAnnotationsToSelection();

            selectedNodes = app.Tree.SelectedNodes;
            
            if isempty(selectedNodes)
                return;
            end

            [sframe, eframe] = selectedNodes.NodeData.getPlaybackFrames();
            app.stopPlayback();
            app.updateToFrame(sframe);
            app.startPlayback(eframe);
        end

        % Button pushed function: SaveMarkCompleteandCloseButton
        function SaveMarkCompleteandCloseButtonPushed(app, event)
            app.saveAnnotation(app.annotFile);

            if app.isCallerValid()
                app.caller.markGestureComplete(app.giid);
            end

            app.GestureAnnotatorUIFigureCloseRequest();
        end

        % Button pushed function: AddGestureButton
        function AddGestureButtonPushed(app, event)
            if ~app.isCallerValid 
                return
            else
                gid = app.caller.gestureSelector();
                json = app.caller.db.getGestureJson(gid);
                annot = basicReplayGrid.annotFromJson(json);
                app.rootAnnotation.addChild(annot);
                
                app.refreshRootAnnotation();
            end
        end

        % Button pushed function: RemoveGestureButton
        function RemoveGestureButtonPushed(app, event)
            selectedNode = app.Tree.SelectedNodes;

            if isempty(selectedNode)
                app.warn("You must select an annotation to remove");
                return;
            end

            selectedAnnot = selectedNode.NodeData;

            if selectedAnnot.parent ~= app.rootAnnotation
                app.warn("You can only delete whole annotations, not sub-annotations");
                return;
            end

            selectedAnnot.delete();
            app.refreshRootAnnotation();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestureAnnotatorUIFigure = uifigure('Visible', 'off');
            app.GestureAnnotatorUIFigure.Name = 'Gesture Annotator';
            app.GestureAnnotatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @GestureAnnotatorUIFigureCloseRequest, true);
            app.GestureAnnotatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @GestureAnnotatorUIFigureWindowKeyPress, true);
            app.GestureAnnotatorUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @GestureAnnotatorUIFigureWindowKeyRelease, true);
            app.GridLayout2 = uigridlayout(app.GestureAnnotatorUIFigure);
            app.Timeline = uiaxes(app.GridLayout2);
            xlabel(app.Timeline, 'Frame')
            app.Timeline.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Gesture')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FrameSpinner = uispinner(app.GridLayout3);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.FrameSpinner.Tooltip = {'Use arrow keys for finer control'; 'Use shift + arrow keys to move faster'};
            app.FrameSpinner.Value = 1;
            app.SpeedSpinnerLabel = uilabel(app.GridLayout3);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.GridLayout3);
            app.SpeedSpinner.Step = 0.5;
            app.SpeedSpinner.Limits = [0.1 10];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 1;
            app.PlayButton = uibutton(app.GridLayout3, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'icons', 'play-solid.svg');
            app.PlayButton.Tooltip = {'''k'' to toggle'};
            app.PlayButton.Text = 'Play';
            app.SaveAnnotation = uibutton(app.GridLayout3, 'push');
            app.SaveAnnotation.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationPushed, true);
            app.SaveAnnotation.Icon = fullfile(pathToMLAPP, 'icons', 'floppy-disk-solid.svg');
            app.SaveAnnotation.Text = 'Save';
            app.SpeedSpinnerLabel_2 = uilabel(app.GridLayout3);
            app.SpeedSpinnerLabel_2.Text = 'Frame';
            app.MarkCompleteButton = uibutton(app.GridLayout3, 'push');
            app.MarkCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @MarkCompleteButtonPushed, true);
            app.MarkCompleteButton.Enable = 'off';
            app.MarkCompleteButton.Text = 'Mark Complete';
            app.MarkIncompleteButton = uibutton(app.GridLayout3, 'push');
            app.MarkIncompleteButton.ButtonPushedFcn = createCallbackFcn(app, @MarkIncompleteButtonPushed, true);
            app.MarkIncompleteButton.Enable = 'off';
            app.MarkIncompleteButton.Text = 'Mark Incomplete';
            app.SaveMarkCompleteandCloseButton = uibutton(app.GridLayout3, 'push');
            app.SaveMarkCompleteandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMarkCompleteandCloseButtonPushed, true);
            app.SaveMarkCompleteandCloseButton.Text = 'Save, Mark Complete, and Close';
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            app.Tree.Tooltip = {'Press ''shift'' while scrubbing to move annotations. '; 'Press ''a'' to move annotation to cursor '};
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.AddGestureButton = uibutton(app.GridLayout2, 'push');
            app.AddGestureButton.ButtonPushedFcn = createCallbackFcn(app, @AddGestureButtonPushed, true);
            app.AddGestureButton.Visible = 'off';
            app.AddGestureButton.Text = 'Add Gesture';
            app.RemoveGestureButton = uibutton(app.GridLayout2, 'push');
            app.RemoveGestureButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGestureButtonPushed, true);
            app.RemoveGestureButton.Visible = 'off';
            app.RemoveGestureButton.Text = 'Remove Gesture';
            % Show the figure after all components are created
            app.GestureAnnotatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicReplayGrid(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GestureAnnotatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GestureAnnotatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureAnnotatorUIFigure)
        end
    end
end