classdef exportSHREC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportProjectUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        ExportButton                    matlab.ui.control.Button
        ExportFolderLabel               matlab.ui.control.Label
        FileSelector                    wt.FileSelector
        FormatStringField               matlab.ui.control.EditField
        FileFormatStringEditFieldLabel  matlab.ui.control.Label
        ExportLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        db
        projectName
    end

    properties (Constant, Access = private)
        formatDict = dictionary(...
                'n', 'gesture_name', ...
                'N', 'trial_name', ...
                'g', 'giid', ...
                'G', 'gid', ...
                't', 'tiid', ...
                'T', 'tid', ...
                'p', 'pos', ...
                'P', 'pid', ...
                'i', 'instructions', ...
                'r', 'repetitions', ...
                'd', 'duration', ...
                's', 'is_stream', ...
                'e', 'participant_info' ...
            );
    end

    methods (Static, Access = public)
        function list = getFormatCharList()
            list = strjoin(keys(exportSHREC.formatDict), "");
        end

        function isValid = isValidFormatString(formatString)
            isValid = matches(formatString, regexpPattern('[a-zA-Z0-9_%\-]+')) ;
            isValid = isValid && numel(regexp(formatString, strcat('%[^', exportSHREC.getFormatCharList, ']|%$'))) == 0;
            isValid = isValid && numel(regexp(formatString, '%g')) > 0;
        end
    
        function populated = populateFormatString(formatString, row)
            expression = strcat("%(?<formatChar>[", exportSHREC.getFormatCharList ,"])");
            replaceFunc = @(char) string(row{1, exportSHREC.formatDict(char)}); %#ok<NASGU>
            replace = '${replaceFunc($<formatChar>)}';
            
            populated = regexprep(formatString, expression, replace);
        end
    end
    
    methods (Access = private)
        
         function warn(app, message)
            uialert( ...
                app.ExportProjectUIFigure, ...
                message, ...
                'Warning', ...
                'Icon', 'warning');
         end

         function inform(app, message)
            uialert( ...
                app.ExportProjectUIFigure, ...
                message, ...
                'Information', ...
                'Icon', 'info');
         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, db, projectName)
            app.db = db;
            app.projectName = projectName;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            folderLocation = app.FileSelector.FullPath;
            mainFolder = getMainFolder();
            testFolder = fullfile(mainFolder, "test");
            gestureFolder = fullfile(mainFolder, "gestures");
            gestureDataFolder = fullfile(gestureFolder, "data");
            gestureAnnotFolder = fullfile(gestureFolder, "annotations");
            testDataFolder = fullfile(testFolder, "data");
            testAnnotFolder = fullfile(testFolder, "annotations");

            if exist(mainFolder, 'dir')
                app.warn(strcat("There already exists a folder with the name ", app.projectName, " in this folder. ", ...
                    "Pick another folder."));
                return;
            end

            allRows = app.db.getAllAttributesOfAllCompleteGestureInstances();
            formatString = app.FormatStringField.Value;

            if ~exportSHREC.isValidFormatString(formatString)
                app.warn("This is an invalid format string. Ensure you include '%g' somewhere in your string " + ...
                    "to ensure uniqueness.")
                return;
            end

            % export loop
            try 
                mkdir(gestureDataFolder);
                mkdir(gestureAnnotFolder);
                mkdir(testDataFolder);
                mkdir(testAnnotFolder);

                for rowIndex = 1:height(allRows)
                    currentRow = allRows(rowIndex, :);
                    newFileName = exportSHREC.populateFormatString(formatString, currentRow);
                    
                    isStream = currentRow{1, 'is_stream'};
                    giid = currentRow{1, 'giid'};
                    gestureName = currentRow{1, 'gesture_name'};

                    [exists, annotFile] = app.db.getAnnotFile(giid);
    
                    if ~exists
                        app.warn("There was no annotation for giid: " + giid);
                        return;
                    end

                    annotJson = fileread(annotFile);
                    annot = basicReplayGrid.annotFromJson(annotJson);
                    annotRecord = strcat(string(giid), annot.getExportRecord);

                    dataFile = app.db.getDataFileOfGiid(giid);

                    if ~isStream
                        newFilePath = getFilePath(gestureDataFolder, newFileName);                    
                        copyfile(dataFile, newFilePath);

                        writelines(annotRecord, getFilePath(gestureAnnotFolder, gestureName), "WriteMode", "append");
                    else
                        newFilePath = getFilePath(testDataFolder, newFileName);
                        copyfile(dataFile, newFilePath);
                        writelines(annotRecord, getFilePath(testAnnotFolder, gestureName), "WriteMode", "append");
                    end
                end

                app.inform("Data successfully exported. You may close this window.");
            catch ME
                disp(ME);
                app.warn("There was an issue while exporting the data");
                return;
            end

            function folderPath = getMainFolder()
                folderPath = fullfile(folderLocation, ...
                    strcat( ...
                        app.projectName, ...
                        "-export-", ...
                        string(datetime('now', 'TimeZone', 'local', 'Format', 'dd-MM-yy_HH-mm-ss'))));
            end


            function filePath = getFilePath(folder, fileName)
                filePath = fullfile(folder, strcat(fileName, ".csv"));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportProjectUIFigure = uifigure('Visible', 'off');
            app.ExportProjectUIFigure.Name = 'Export Project';
            app.GridLayout = uigridlayout(app.ExportProjectUIFigure);
            app.ExportLabel = uilabel(app.GridLayout);
            app.ExportLabel.Text = 'Export';
            app.FileFormatStringEditFieldLabel = uilabel(app.GridLayout);
            app.FileFormatStringEditFieldLabel.Text = 'File Format String';
            app.FormatStringField = uieditfield(app.GridLayout, 'text');
            app.FormatStringField.Value = '%n-id-%g-participant-%P';
            app.FileSelector = wt.FileSelector(app.GridLayout);
            app.FileSelector.SelectionType = 'folder';
            app.ExportFolderLabel = uilabel(app.GridLayout);
            app.ExportFolderLabel.Text = 'Export Folder';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'%n: gesture_name,'; '%N: trial_name'; '%g: giid,'; '%G: gid,'; '%t: tiid,'; '%T: tid,'; '%p: pos,'; '%P: pid,'; '%i: instructions,'; '%r: repetitions,'; '%d: duration,'; '%e: participant_info,'; '%s: is_stream'};
            % Show the figure after all components are created
            app.ExportProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exportSHREC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportProjectUIFigure)
        end
    end
end